classdef onlyGyroReadappCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartGyroButton         matlab.ui.control.StateButton
        GyroscopeDecisionPanel  matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        ForwardLampLabel        matlab.ui.control.Label
        ForwardLamp             matlab.ui.control.Lamp
        LeftLampLabel           matlab.ui.control.Label
        LeftLamp                matlab.ui.control.Lamp
        RightLampLabel          matlab.ui.control.Label
        RightLamp               matlab.ui.control.Lamp
        BackLampLabel           matlab.ui.control.Label
        BackLamp                matlab.ui.control.Lamp
        EEGDecisionPanel        matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.StartGyroButton.Value;
            startProgram = 'True';

            xt = 0;
            yt = 0;
            zt = 0;
            xAngVal = 0;
            yAngVal = 0;
            zAngVal = 0;
            startSpot = 0;
            step = 0.00001 ; % lowering step has a number of cycles and then acquire more data
            
            frontThershold = -5;
            backThershold = 5;
            leftThershold = 5;
            rightThershold = -5;
            printFlag = 0;
            fprintf("\t\tWelcome to Smart Wheelchair\n\n")
            fprintf("\t\tOpen app and click on START\n\n\n\n")
            
            while (startProgram == 'True')
                url = "https://iotfirebase-bdfd4.firebaseio.com/.json";
                data = webread(url);
                xAngVelocity = str2double(data.SmartWheelchair.xAngVelocity);
                yAngVelocity = str2double(data.SmartWheelchair.yAngVelocity);
                zAngVelocity = str2double(data.SmartWheelchair.zAngVelocity);
                
                if (yAngVelocity < frontThershold)
                    if(printFlag == 0)
                        fprintf("Moving Front\n")
                        app.ForwardLamp.Color = 'green';
                        printFlag = 1;
                    end
                elseif (yAngVelocity > backThershold)
                    if(printFlag == 0)
                        fprintf("Moving Back\n")
                        app.BackLamp.Color = 'green';
                        printFlag = 1;
                    end
                elseif (xAngVelocity > leftThershold)
                    if(printFlag == 0)
                        fprintf("Turn Left\n")
                        app.LeftLamp.Color = 'green';
                        printFlag = 1;
                    end
                elseif (xAngVelocity < rightThershold)
                    if(printFlag == 0)
                        fprintf("Turn Right\n")
                        app.RightLamp.Color = 'green';
                        printFlag = 1;
                    end
                else
                    printFlag = 0;
                    app.ForwardLamp.Color = [1.00 0.41 0.16];
                    app.BackLamp.Color = [1.00 0.41 0.16];
                    app.LeftLamp.Color = [1.00 0.41 0.16];
                    app.RightLamp.Color = [1.00 0.41 0.16];
                end
                
                if (xAngVelocity ~= 0)
                    xAngVal = [ xAngVal, xAngVelocity ];
                    subplot(3,1,1);
                    plot(xAngVal);
                    title('xAngVelocity')  
                    if ((xt/step)-500 < 0)
                        startSpot = 0;
                    else
                        startSpot = (xt/step)-500;
                    end
                    axis([ startSpot, (xt/step+10), -10 , 10 ]);
                    grid
                    xt = xt + step;
                    drawnow;
                    pause(0.01)
            
                    yAngVal = [ yAngVal, yAngVelocity ];
                    subplot(3,1,2);
                    plot(yAngVal);
                    title('yAngVelocity')  
                    if ((yt/step)-500 < 0)
                        startSpot = 0;
                    else
                        startSpot = (yt/step)-500;
                    end
                    axis([ startSpot, (yt/step+10), -10 , 10 ]);
                    grid
                    yt = yt + step;
                    drawnow;
                    pause(0.01)
            
                    zAngVal = [ zAngVal, zAngVelocity ];
                    subplot(3,1,3);
                    plot(zAngVal);
                    title('zAngVelocity')  
                    if ((zt/step)-500 < 0)
                        startSpot = 0;
                    else
                        startSpot = (zt/step)-500;
                    end
                    axis([ startSpot, (zt/step+10), -10 , 10 ]);
                    grid
                    zt = zt + step;
                    drawnow;
                    pause(0.01)
                end
            end
        end

        % Value changed function: StartGyroButton
        function StartGyroButtonValueChanged(app, event)
            value = app.StartGyroButton.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartGyroButton = uibutton(app.UIFigure, 'state');
            app.StartGyroButton.ValueChangedFcn = createCallbackFcn(app, @StartGyroButtonValueChanged, true);
            app.StartGyroButton.Text = 'Start Gyro';
            app.GyroscopeDecisionPanel = uipanel(app.UIFigure);
            app.GyroscopeDecisionPanel.Title = 'Gyroscope Decision';
            app.GridLayout = uigridlayout(app.GyroscopeDecisionPanel);
            app.ForwardLampLabel = uilabel(app.GridLayout);
            app.ForwardLampLabel.Text = 'Forward';
            app.ForwardLamp = uilamp(app.GridLayout);
            app.LeftLampLabel = uilabel(app.GridLayout);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.GridLayout);
            app.RightLampLabel = uilabel(app.GridLayout);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.GridLayout);
            app.BackLampLabel = uilabel(app.GridLayout);
            app.BackLampLabel.Text = 'Back';
            app.BackLamp = uilamp(app.GridLayout);
            app.EEGDecisionPanel = uipanel(app.UIFigure);
            app.EEGDecisionPanel.Title = 'EEG Decision';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = onlyGyroReadappCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end